classdef CollapseFragility < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        SagEditFieldLabel  matlab.ui.control.Label
        SagEditField       matlab.ui.control.EditField
        Label              matlab.ui.control.Label
        EditField_2        matlab.ui.control.EditField
        Button             matlab.ui.control.Button
        Panel              matlab.ui.container.Panel
        gLabel             matlab.ui.control.Label
        gEditField         matlab.ui.control.EditField
        EditField_3Label   matlab.ui.control.Label
        EditField_3        matlab.ui.control.EditField
        Button_2           matlab.ui.control.Button
    end


    properties (Access = private)
        Sa % Description
        Pcon
    end

    properties (Access = public)
        CollFragObj % Description
    end


    methods (Access = private)

        % Value changed function: SagEditField
        function SagEditFieldValueChanged(app, event)
            value = app.SagEditField.Value;
            app.Sa=str2num(value);
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            app.Pcon=str2num(value);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.CollFragObj=CollFrag(app.Sa,app.Pcon);
            app.gEditField.Value=num2str(app.CollFragObj.medianSa); %显示均值
            app.EditField_3.Value=num2str(app.CollFragObj.sigmalnSa); %显示标准差
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            ButtonPushed(app); %计算
            app.CollFragObj.plotFit(); %画图
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = '倒塌易损性曲线';
            app.SagEditFieldLabel = uilabel(app.UIFigure);
            app.SagEditFieldLabel.Text = 'Sa (g):';
            app.SagEditField = uieditfield(app.UIFigure, 'text');
            app.SagEditField.ValueChangedFcn = createCallbackFcn(app, @SagEditFieldValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '倒塌比例：';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '计算';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '结果';
            app.gLabel = uilabel(app.Panel);
            app.gLabel.Text = '倒塌中值(g):';
            app.gEditField = uieditfield(app.Panel, 'text');
            app.EditField_3Label = uilabel(app.Panel);
            app.EditField_3Label.Text = '对数标准差:';
            app.EditField_3 = uieditfield(app.Panel, 'text');
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '显示';
        end
    end

    methods (Access = public)

        % Construct app
        function app = CollapseFragility

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end